classdef Hannah_Johnson_T92023 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        TabGroup           matlab.ui.container.TabGroup
        SearchTab          matlab.ui.container.Tab
        Panel              matlab.ui.container.Panel
        CityDropDownLabel  matlab.ui.control.Label
        CityDropDown       matlab.ui.control.DropDown
        PriceSliderLabel   matlab.ui.control.Label
        PriceSlider        matlab.ui.control.Slider
        SearchButton       matlab.ui.control.Button
        LookingforinspirationandtofindsomeonelikeyouinengineeringLabel  matlab.ui.control.Label
        Tab_2              matlab.ui.container.Tab
        Panel_2            matlab.ui.container.Panel
        MapsButton         matlab.ui.control.Button
        TextArea           matlab.ui.control.TextArea
        Image              matlab.ui.control.Image
        WerecommendLabel   matlab.ui.control.Label
        Label              matlab.ui.control.Label
    end

    
    properties (Access = private)
        data % Description
        url
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
        pause(0.1);
        app.SearchButton.BackgroundColor=[0.87,0.76,0.88]   
        app.SearchButton.Text="Redirecting..."
  
        app.TabGroup.SelectedTab=app.Tab_2;
        
        
        app.SearchButton.BackgroundColor=[0.96,0.96,0.96]   
        app.SearchButton.Text="Search"
        
       price=round(app.PriceSlider.Value);
       disp(price)
       
       location=app.CityDropDown.Value;
 
    
       [name]=test(price);
       [loc]=locator(location);
       disp(name);
       disp(loc)
       tf=strcmp(loc,name);
       
       jorb=find(tf==1)
       if jorb>1
       truename=loc(1,jorb);
       elseif jorb==1;
           truename=name(jorb)
       end
       
       address=addressfind(truename)
       address=string(address);
       
%        for i=1:length(tf)
%            if tf(1,i)==1;
%                truename=name(i);
%                address=addressfind(truename)
%                address=string(address);
%         
%             
%            end
%            i=i+1
%        end
%         
      
       
           truename=string(truename)
           app.Label.Text=truename;
           
          if truename=="Mateo"
           app.Image.ImageSource="mateo.jpg";
           app.url='https://www.google.com/maps?q=mateo+boulder&rlz=1C1CHBF_enUS858US858&um=1&ie=UTF-8&sa=X&ved=2ahUKEwi4h7rY6p_9AhUSJDQIHTK8DusQ_AUoAnoECAEQBA';
          elseif truename=="TeaLee's Tea House and Book store"
              app.Image.ImageSource="tealee.jpg"
              app.url="https://www.google.com/maps/place/TeaLee's+Teahouse/@39.7504421,-104.9863634,17z/data=!3m1!4b1!4m5!3m4!1s0x876c792797af0629:0xbfd56094ea408e03!8m2!3d39.7504421!4d-104.9841747";
          elseif truename=="Novel Strand Brewing Co."
              app.Image.ImageSource="novel.jpg";
              app.url="https://www.google.com/maps?q=novel+strand+brewing&rlz=1C1CHBF_enUS858US858&um=1&ie=UTF-8&sa=X&ved=2ahUKEwi8w5mvh6D9AhVLADQIHaUGCt4Q_AUoAXoECAEQAw"
          end
          
           
           app.TextArea.Value=sprintf("%s is a restaurant in %s. It is located at %s",truename,location,address);


        end

        % Value changed function: PriceSlider
        function PriceSliderValueChanged(app, event)
            value = app.PriceSlider.Value;
         
         
        end

        % Value changed function: CityDropDown
        function CityDropDownValueChanged(app, event)
            value = app.CityDropDown.Value;
            
        end

        % Button pushed function: MapsButton
        function MapsButtonPushed(app, event)
            
        web(app.url)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SearchTab = uitab(app.TabGroup);
            app.SearchTab.Title = 'Search';
            app.Panel = uipanel(app.SearchTab);
            app.CityDropDownLabel = uilabel(app.Panel);
            app.CityDropDownLabel.Text = 'City';
            app.CityDropDown = uidropdown(app.Panel);
            app.CityDropDown.Items = {'Denver', 'Boulder'};
            app.CityDropDown.ValueChangedFcn = createCallbackFcn(app, @CityDropDownValueChanged, true);
            app.CityDropDown.Value = 'Denver';
            app.PriceSliderLabel = uilabel(app.Panel);
            app.PriceSliderLabel.Text = 'Price';
            app.PriceSlider = uislider(app.Panel);
            app.PriceSlider.Limits = [0 3];
            app.PriceSlider.MajorTicks = [0 1 2 3];
            app.PriceSlider.ValueChangedFcn = createCallbackFcn(app, @PriceSliderValueChanged, true);
            app.PriceSlider.MinorTicks = [];
            app.SearchButton = uibutton(app.Panel, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.LookingforinspirationandtofindsomeonelikeyouinengineeringLabel = uilabel(app.Panel);
            app.LookingforinspirationandtofindsomeonelikeyouinengineeringLabel.Text = 'Find POC locally owned restaurants near you!';
            app.Tab_2 = uitab(app.TabGroup);
            app.Panel_2 = uipanel(app.Tab_2);
            app.MapsButton = uibutton(app.Panel_2, 'push');
            app.MapsButton.ButtonPushedFcn = createCallbackFcn(app, @MapsButtonPushed, true);
            app.MapsButton.Text = 'Maps';
            app.TextArea = uitextarea(app.Panel_2);
            app.Image = uiimage(app.Panel_2);
            app.WerecommendLabel = uilabel(app.Panel_2);
            app.WerecommendLabel.Text = 'We recommend:';
            app.Label = uilabel(app.Panel_2);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Hannah_Johnson_T92023

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end